classdef VisioGuard_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana1                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VentanaInicio_Tab               matlab.ui.container.Tab
        Contra_EditField                matlab.ui.control.EditField
        Error_Label_5                   matlab.ui.control.Label
        Contra_Label                    matlab.ui.control.Label
        Error_Label_1                   matlab.ui.control.Label
        Error_Label_2                   matlab.ui.control.Label
        Error_Label_3                   matlab.ui.control.Label
        Error_Label_4                   matlab.ui.control.Label
        Centros_Label                   matlab.ui.control.Label
        Centros_EditField               matlab.ui.control.EditField
        ID_Label                        matlab.ui.control.Label
        ID_EditField                    matlab.ui.control.EditField
        Fecha_Label                     matlab.ui.control.Label
        AcercaDe_Button                 matlab.ui.control.Button
        CE_Image                        matlab.ui.control.Image
        GlaucoScan_Label                matlab.ui.control.Label
        Usuario_Image                   matlab.ui.control.Image
        Nombre_Label                    matlab.ui.control.Label
        Apellidos_Label                 matlab.ui.control.Label
        ErrorInicio_Label               matlab.ui.control.Label
        Nombre_EditField                matlab.ui.control.EditField
        Apellidos_EditField             matlab.ui.control.EditField
        Continuar_Button                matlab.ui.control.Button
        VisioGuard_Image                matlab.ui.control.Image
        MU_Image                        matlab.ui.control.Image
        Marco2_Image_2                  matlab.ui.control.Image
        Tab2                            matlab.ui.container.Tab
        Marco_6                         matlab.ui.control.Image
        VerMas_Button                   matlab.ui.control.Button
        CE4_Image_4                     matlab.ui.control.Image
        Notas_Button                    matlab.ui.control.Button
        Mas_Button                      matlab.ui.control.Button
        Espere_Label                    matlab.ui.control.Label
        ValorMaximoResult_Label         matlab.ui.control.Label
        VarianzaBordesResult_Label      matlab.ui.control.Label
        RangoDinamicoResult_Label       matlab.ui.control.Label
        DesviacionEstandarResult_Label  matlab.ui.control.Label
        EntropiaResult_Label            matlab.ui.control.Label
        Valor_maximo_bordes_Label       matlab.ui.control.Label
        Varianza_bordes_Label           matlab.ui.control.Label
        Rango_dinamico_Label            matlab.ui.control.Label
        Desviacion_estandar_Label       matlab.ui.control.Label
        Entropia_Label                  matlab.ui.control.Label
        Segmentacion_Image              matlab.ui.control.Image
        GlaucoScan2_Label               matlab.ui.control.Label
        Segmentacion_Label              matlab.ui.control.Label
        Calidad_Label                   matlab.ui.control.Label
        PropiedadesdeImagen_Label       matlab.ui.control.Label
        ErrorInsertar_Label             matlab.ui.control.Label
        Guardar_Button                  matlab.ui.control.Button
        Nombreyapellido_Label           matlab.ui.control.Label
        Inserteimagen_Button            matlab.ui.control.Button
        EyeFundus_Image                 matlab.ui.control.Image
        VisioGuard2_Image               matlab.ui.control.Image
        MU2_Image                       matlab.ui.control.Image
        Diagnostico_Label               matlab.ui.control.Label
        Recuadro_Image                  matlab.ui.control.Image
        Recuadro_Image_5                matlab.ui.control.Image
        Marco2_Image_7                  matlab.ui.control.Image
        Propiedades_Tab                 matlab.ui.container.Tab
        HomogeneidadResult_Label        matlab.ui.control.Label
        EnergiaResult_Label             matlab.ui.control.Label
        CorrelacionResult_Label         matlab.ui.control.Label
        ContrasteResult_Label           matlab.ui.control.Label
        MediaAzul_Label_11              matlab.ui.control.Label
        MediaAzul_Label_10              matlab.ui.control.Label
        MediaAzul_Label_9               matlab.ui.control.Label
        MediaAzul_Label_8               matlab.ui.control.Label
        DesemejanzaCGResult_Label       matlab.ui.control.Label
        EntropiaCVResult_Label          matlab.ui.control.Label
        ContrasteCHResult_Label         matlab.ui.control.Label
        HomoCDResult_Label              matlab.ui.control.Label
        HomoCVResult_Label              matlab.ui.control.Label
        HomoCHResult_Label              matlab.ui.control.Label
        MediaAzul_Label_7               matlab.ui.control.Label
        MediaAzul_Label_6               matlab.ui.control.Label
        MediaAzul_Label_5               matlab.ui.control.Label
        MediaAzul_Label_4               matlab.ui.control.Label
        MediaAzul_Label_3               matlab.ui.control.Label
        MediaAzul_Label_2               matlab.ui.control.Label
        DesviacionVerdeResult_Label     matlab.ui.control.Label
        DesciacionRojoResult_Label      matlab.ui.control.Label
        MediaAzulResult_Label           matlab.ui.control.Label
        DesviacionVerde_Label           matlab.ui.control.Label
        DesviacionRojo_Label            matlab.ui.control.Label
        MediaAzul_Label                 matlab.ui.control.Label
        Recuadro_Image_8                matlab.ui.control.Image
        Image8_17                       matlab.ui.control.Image
        RelacionAreaDiscoResul_Label    matlab.ui.control.Label
        AreaCopa_Label                  matlab.ui.control.Label
        AreaDiscoResult_Label           matlab.ui.control.Label
        RelacionAD_Label                matlab.ui.control.Label
        ACopa_Label                     matlab.ui.control.Label
        ADisco_Labe                     matlab.ui.control.Label
        Recuadro_Image_7                matlab.ui.control.Image
        Etiqueta_Label_6                matlab.ui.control.Label
        Recuadro_Image_4                matlab.ui.control.Image
        Etiqueta_Label_5                matlab.ui.control.Label
        Recuadro_Image_3                matlab.ui.control.Image
        Etiqueta_Label_4                matlab.ui.control.Label
        Etiqueta_Label_3                matlab.ui.control.Label
        Etiqueta_Label_2                matlab.ui.control.Label
        ValorMaximoResult_Label_2       matlab.ui.control.Label
        VarianzaBordesResult_Label_2    matlab.ui.control.Label
        RangoDinamicoResult_Label_2     matlab.ui.control.Label
        DesviacionEstandarResult_Label_2  matlab.ui.control.Label
        EntropiaResult_Label_2          matlab.ui.control.Label
        Valor_maximo_bordes_Label_2     matlab.ui.control.Label
        Varianza_bordes_Label_2         matlab.ui.control.Label
        Rango_dinamico_Label_2          matlab.ui.control.Label
        Desviacion_estandar_Label_2     matlab.ui.control.Label
        Entropia_Label_2                matlab.ui.control.Label
        CE3_Image_2                     matlab.ui.control.Image
        GlaucoScan3_Label_2             matlab.ui.control.Label
        VisioGuard3_Image2_2            matlab.ui.control.Image
        MU3_Image_3                     matlab.ui.control.Image
        Marco3_Image_3                  matlab.ui.control.Image
        Recuadro_Image_6                matlab.ui.control.Image
        Histograma_Axes                 matlab.ui.control.UIAxes
        Mas_Tab                         matlab.ui.container.Tab
        Image8_16                       matlab.ui.control.Image
        CE_Image_2                      matlab.ui.control.Image
        Marco_5                         matlab.ui.control.Image
        Marco_4                         matlab.ui.control.Image
        Marco_2                         matlab.ui.control.Image
        Marco                           matlab.ui.control.Image
        SegmentacionCopa_Image          matlab.ui.control.Image
        SegmentacionDisco_Image         matlab.ui.control.Image
        VS_Image                        matlab.ui.control.Image
        ExtraccionROI_Image             matlab.ui.control.Image
        SegmentacionCopa_Label          matlab.ui.control.Label
        SegmentacionDisco_Label         matlab.ui.control.Label
        ExtraccionROI_Label             matlab.ui.control.Label
        ImagenOriginal_Label            matlab.ui.control.Label
        GlaucoScan2_Label_2             matlab.ui.control.Label
        VS_Label                        matlab.ui.control.Label
        VisioGuard2_Image_2             matlab.ui.control.Image
        MU2_Image_2                     matlab.ui.control.Image
        ImagenOriginal_Image            matlab.ui.control.Image
        Marco2_Image_5                  matlab.ui.control.Image
        Notas_Tab                       matlab.ui.container.Tab
        Image8_15                       matlab.ui.control.Image
        CE_Image_3                      matlab.ui.control.Image
        Notas_TextArea                  matlab.ui.control.TextArea
        ExtraccionROI_Label_2           matlab.ui.control.Label
        GlaucoScan2_Label_3             matlab.ui.control.Label
        VisioGuard2_Image_3             matlab.ui.control.Image
        MU2_Image_3                     matlab.ui.control.Image
        Marco2_Image_6                  matlab.ui.control.Image
        AcercaDe_Tab                    matlab.ui.container.Tab
        Image8_14                       matlab.ui.control.Image
        SobreNosotros_Image_2           matlab.ui.control.Image
        SobreNosotros_Button_2          matlab.ui.control.Button
        Etiqueta_Image                  matlab.ui.control.Image
        SobreNosotros_Image             matlab.ui.control.Image
        Instrucciones_Image             matlab.ui.control.Image
        AcercaDe2_Label                 matlab.ui.control.Label
        Instruccione_Button             matlab.ui.control.Button
        Etiqueta_Button                 matlab.ui.control.Button
        SobreNosotros_Button            matlab.ui.control.Button
        CE2_Image                       matlab.ui.control.Image
        GlaucoScan_Label_2              matlab.ui.control.Label
        VisioGuard3_Image               matlab.ui.control.Image
        MU3_Image                       matlab.ui.control.Image
        Marco2_Image_8                  matlab.ui.control.Image
        Sobre_nosotros_Tab              matlab.ui.container.Tab
        Image8_13                       matlab.ui.control.Image
        Encuentranos_Button             matlab.ui.control.Button
        Equipo_Button                   matlab.ui.control.Button
        Slogan_Label                    matlab.ui.control.Label
        Texto_sobre_nosotros_TextArea   matlab.ui.control.TextArea
        Sobre_nosotros_Label            matlab.ui.control.Label
        CE4_Image                       matlab.ui.control.Image
        Inicio4_Button                  matlab.ui.control.Button
        GlaucoScan4_Label               matlab.ui.control.Label
        VisioGuard4_Image               matlab.ui.control.Image
        MU4_Image                       matlab.ui.control.Image
        Marco2_Image_9                  matlab.ui.control.Image
        Equipo_Tab                      matlab.ui.container.Tab
        Image8_12                       matlab.ui.control.Image
        Ane_Label_2                     matlab.ui.control.Label
        paula_Label_2                   matlab.ui.control.Label
        Asi_Label_                      matlab.ui.control.Label
        Elisa_Label_2                   matlab.ui.control.Label
        IkerAmor_Label_2                matlab.ui.control.Label
        Iker_Image                      matlab.ui.control.Image
        Asier_Label                     matlab.ui.control.Label
        Elisa_Label                     matlab.ui.control.Label
        Paula_Label                     matlab.ui.control.Label
        Ane_Label                       matlab.ui.control.Label
        IkerAmor_Label                  matlab.ui.control.Label
        paula_Image                     matlab.ui.control.Image
        ane_Image                       matlab.ui.control.Image
        Asier_Image                     matlab.ui.control.Image
        Elisa_Image                     matlab.ui.control.Image
        Equipo_Label                    matlab.ui.control.Label
        Inicio4_Button_3                matlab.ui.control.Button
        CE4_Image_3                     matlab.ui.control.Image
        GlaucoScan4_Label_3             matlab.ui.control.Label
        VisioGuard4_Image_3             matlab.ui.control.Image
        MU4_Image_3                     matlab.ui.control.Image
        Marco2_Image_10                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        Quality
        Diagnostico
        archivo


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            %Fecha y hora en la pantalla de inicio
            fecha= datetime('now', 'Format', 'dd/MM/yyyy HH:mm');
            fecha_str = char(fecha);
            app.Fecha_Label.Text = fecha_str;           
        end

        % Button pushed function: Continuar_Button
        function Continuar_ButtonPushed(app, event)
            %Contraseñas para iniciar sesion
            Contras = {'bizkaia24', 'gipuzkoa24', 'araba24', 'glauco24', 'scan24'};
            % Crear una tabla con las contras
            TContra = table(Contras', 'VariableNames', {'Contras'});
            Value=string(app.Contra_EditField.Value);%pasar a string pa comparar

            Valid=0;%De primeras Valid=0
            for i=1:size(TContra,1)
                if TContra{i,1}==Value%tienen que ser dos strings
                    Valid=1;%Si coinicide la caja de texto con alguna de las contraseñas Valid=1
                end
            end
            
            if (isempty(app.Nombre_EditField.Value) || isempty(app.Apellidos_EditField.Value) || isempty(app.ID_EditField.Value)|| isempty(app.Centros_EditField.Value))|| isempty(app.Contra_EditField.Value)
                app.ErrorInicio_Label.Visible = 'on'; %si alguna casilla vacia que salte el mensaje
                %Advertencias on
                if (isempty(app.Nombre_EditField.Value))
                    app.Error_Label_1.Visible = 'on';
                end
                if (isempty(app.Centros_EditField.Value))
                    app.Error_Label_3.Visible = 'on';
                end
                if (isempty(app.Apellidos_EditField.Value))
                    app.Error_Label_2.Visible = 'on';
                end
                if (isempty(app.ID_EditField.Value))
                    app.Error_Label_4.Visible = 'on';
                end
                if (isempty(app.Contra_EditField.Value))
                    app.Error_Label_5.Visible = 'on';
                    msgbox('Asegurase de introducir la contraseña',"Warning","warn");
                end
            else
                app.ErrorInicio_Label.Visible = 'off';%si esta todo marcado que no aparezca
            end
            if (~isempty(app.Contra_EditField.Value)) && (Valid~=1)
                    msgbox('Contraseña incorrecta',"Error","error");
            end
            
            %Advertencias off
            if (~isempty(app.Nombre_EditField.Value))
                    app.Error_Label_1.Visible = 'off';
            end
            if (~isempty(app.Apellidos_EditField.Value))
                    app.Error_Label_2.Visible = 'off';
            end
            if (~isempty(app.Centros_EditField.Value))
                    app.Error_Label_3.Visible = 'off';
            end
            if (~isempty(app.ID_EditField.Value))
                    app.Error_Label_4.Visible = 'off';
            end
            if (~isempty(app.Contra_EditField.Value))
                    app.Error_Label_5.Visible = 'off';
            end

            if(~isempty(app.Nombre_EditField.Value) && ~isempty(app.Apellidos_EditField.Value)&& ~isempty(app.ID_EditField.Value)&& ~isempty(app.Centros_EditField.Value))&& ~isempty(app.Contra_EditField.Value)&&  Valid==1
                app.TabGroup.SelectedTab = app.Tab2; 
            end
            
        end

        % Callback function
        function Inserteimagen_ButtonPushed(app, event)
            
        end

        % Callback function
        function NuevoPaciente_ButtonPushed(app, event)
            %Nuevo paciente (volver pagina principal)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
            %Imagen, labels y botones del tab2 de 0
            app.Nombre_EditField.Value='';
            app.Apellidos_EditField.Value='';
            app.Apellidos_EditField.Value='';
            app.EyeFundus_Image.Visible='off';
            app.Inserteimagen_Button.Visible='on';
            app.ImportanciaVariables_Button.Visible='off';
            app.Recuadro_Image.Visible='off';
            %app.Guardar_Button.Visible='off';
            app.Nombreyapellido_Label.Visible='off';
            app.Recuadro2_Image.Visible='off';
            app.Entropia_Label.Visible='off';
            app.Desviacion_estandar_Label.Visible='off';
            app.Rango_dinamico_Label.Visible='off';
            app.Varianza_bordes_Label.Visible='off';
            app.Valor_maximo_bordes_Label.Visible='off';
        end

        % Button pushed function: AcercaDe_Button
        function AcercaDe_ButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Callback function
        function Inicio_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Instruccione_Button
        function Instruccione_ButtonButtonPushed(app, event)
            %app.TabGroup.SelectedTab = app.Instrucciones_de_uso_Tab;
            %URLInstrucc = 'https://drive.google.com/file/d/16E_-5RLyH1ObUvuuxaeT5oHh0dBDfMps/view?usp=sharing';  
            %web(URLInstrucc, '-browser');
        end

        % Callback function
        function Inicio3_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Callback function
        function Inicio3_Button_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Inicio4_Button
        function Inicio4_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Etiqueta_Button
        function Etiqueta_ButtonButtonPushed(app, event)
            %app.TabGroup.SelectedTab = app.Propiedades_Tab;
            URL = 'https://drive.google.com/file/d/16E_-5RLyH1ObUvuuxaeT5oHh0dBDfMps/view?usp=sharing';  
            web(URL, '-browser');
        end

        % Button pushed function: SobreNosotros_Button
        function SobreNosotros_ButtonButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Sobre_nosotros_Tab;
        end

        % Callback function
        function Atras4_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Callback function
        function Atras3_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Callback function
        function Atras4_Button_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Callback function
        function Inicio4_Button_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Callback function
        function ImportanciaVariables_ButtonPushed(app, event)
            %app.TabGroup.SelectedTab = app.BaseDatos_Tab;
        end

        % Button pushed function: Inserteimagen_Button
        function Inserteimagen_ButtonPushed2(app, event)
            app.Espere_Label.Visible='on';
            [file, location] = uigetfile({'*.jpg';'*.png';}, 'Seleccione una imagen');
            app.archivo=file;
            if file ~= 0
                app.ErrorInsertar_Label.Visible='off';
                
                %RETINOGRAFIA
                % Ubicacion de la imagen
                full_location = fullfile(location, file);
                % Mostrar la imagen en "Eye Fundus"
                app.EyeFundus_Image.ImageSource = full_location;app.Espere_Label.Visible='off';
                app.ImagenOriginal_Image.ImageSource = full_location;
                %app.EyeFundus_Image_2.ImageSource = full_location;


                %MOSTRAR ELEMENTOS INTERFAZ
                %Mostrar la imagen y nombre paciente y esconder el boton para introducir la imagen
                app.EyeFundus_Image.Visible='on';
                app.Segmentacion_Image.Visible='on';
                app.Inserteimagen_Button.Visible='off';
                %app.Guardar_Button.Visible='on';
                %Nombre y apellidos paciente
                app.Nombreyapellido_Label.Text=['Retinografía de ', app.ID_EditField.Value];
                app.Nombreyapellido_Label.Visible='on';
                %Segmen, propiedades, calidad y diagnostico
                app.PropiedadesdeImagen_Label.Visible='on';
                app.Recuadro_Image.Visible='on';
                app.Segmentacion_Label.Visible='on';
                app.Diagnostico_Label.Visible='on';
                app.Entropia_Label.Visible='on';
                app.Desviacion_estandar_Label.Visible='on';
                app.Rango_dinamico_Label.Visible='on';
                app.Varianza_bordes_Label.Visible='on';
                app.Valor_maximo_bordes_Label.Visible='on';
                app.EntropiaResult_Label.Visible='on';
                app.DesviacionEstandarResult_Label.Visible='on';
                app.RangoDinamicoResult_Label.Visible='on';
                app.VarianzaBordesResult_Label.Visible='on';
                app.ValorMaximoResult_Label.Visible='on';
                app.Mas_Button.Visible='on';
                app.Notas_Button.Visible='on';
                app.Guardar_Button.Visible='on';
                app.VerMas_Button.Visible='on';
                app.Recuadro_Image_5.Visible='on';
                app.Marco_6.Visible='on';

                %EXTRACCIÓN PROPIEDADES
                I=imread(full_location);
                Igray=im2gray(I);
                Entropy=entropy(double(Igray)); 
                app.EntropiaResult_Label.Text = num2str(Entropy);app.EntropiaResult_Label.Visible='on';
                app.EntropiaResult_Label_2.Text = num2str(Entropy);

                Contrast=std2(double(Igray)); 
                app.DesviacionEstandarResult_Label.Text = num2str(Contrast);app.DesviacionEstandarResult_Label.Visible='on';%desviación estándar de los valores de píxeles en una imagen en escala de grises
                app.DesviacionEstandarResult_Label_2.Text = num2str(Contrast);
                Imin=min(I(:));Imax=max(I(:));
                TarteDinamiko=Imax-Imin; %tarte dinamiko (las de bajo contraste lo tienen bajo)

                app.RangoDinamicoResult_Label.Text = num2str(TarteDinamiko);app.RangoDinamicoResult_Label.Visible='on';
                app.RangoDinamicoResult_Label_2.Text = num2str(TarteDinamiko);

                %La transformada de Laplace para la detección de las ertzak
                LaplazFilter=[0 1 0; 1 -4 1; 0 1 0];L=imfilter(I,LaplazFilter);
                Laplacianmax=max(double(L(:)));%valor maximo
                app.ValorMaximoResult_Label.Text = num2str(Laplacianmax);app.ValorMaximoResult_Label.Visible='on';
                app.ValorMaximoResult_Label_2.Text = num2str(Laplacianmax);

                Laplacianvar=var(double(L(:)));%varianza
                app.VarianzaBordesResult_Label.Text = num2str(Laplacianvar);app.VarianzaBordesResult_Label.Visible='on';
                app.VarianzaBordesResult_Label_2.Text = num2str(Laplacianvar);

                %MODELO MACHINE LEARNING (CALIDAD)
                Table=table(Entropy, Contrast, TarteDinamiko, Laplacianmax, Laplacianvar);
                load('FineKNNCrossValidation.mat');
                [ypred,~] = FineKNNCrossValidation.predictFcn(Table);

                if (ypred==4)
                    app.Calidad_Label.Text='Imagen de CALIDAD CORRECTA';
                    app.Quality='Correcta';
                elseif(ypred==3)
                    app.Calidad_Label.Text='Imágen con RUIDO';
                    app.Diagnostico_Label.Text='ADVERTENCIA. ¡La calidad de la imagen no es suficiente para realizar un diagnóstico preciso!';
                    app.Quality='Ruido';
                elseif(ypred==2)
                    app.Calidad_Label.Text='Imágen DESENFOCADA';
                    app.Diagnostico_Label.Text='ADVERTENCIA. ¡La calidad de la imagen no es suficiente para realizar un diagnóstico preciso!';
                    app.Quality='Desenfocada';
                elseif(ypred==1)
                    app.Calidad_Label.Text='Imágen de BAJO CONTRASTE';
                    app.Diagnostico_Label.Text='ADVERTENCIA. ¡La calidad de la imagen no es suficiente para realizar un diagnóstico preciso!';
                    app.Quality='Bajo contraste';
                end
                app.Calidad_Label.Visible='on';
            else
                app.ErrorInsertar_Label.Visible='on';
                app.Espere_Label.Visible='off';
            end
            

    if (ypred==4)
            %CALIDAD
            %Extraccion ROI
            I=imread(full_location);
            Igreen=I(:,:,2);
            w=ones(341)/341^2;
            Ifilter=imfilter(Igreen,w);
            %Busqueda de maximos y sus cordenadas
            max_intensidad=max(Ifilter(:));
            [filas, columnas] = find(Ifilter == max_intensidad);
            punto_central = [round(mean(filas)), round(mean(columnas))];
            %Coordenadas centro del disco optico (Mediana de todas las coordenadas)
            medianafilas=median(filas); medianacolumnas=median(columnas);
            %Coordenadas de la esquina superior izquierda del recorte (900X900)
            FilaSI = round(medianafilas - 900/2);
            ColumnaSI = round(medianacolumnas - 900/2);    
            % RealizaR el recorte (900X900)
            Icropped = imcrop(I, [ColumnaSI FilaSI 900-1 900-1]);
            app.ExtraccionROI_Image.ImageSource=Icropped;

            %Eliminacion VS
            Igr_v=Icropped(:,:,2);
            ElemEstrukt=strel('disk',20);BH=imbothat(Igr_v,ElemEstrukt);BH=BH>15;%Vessels' mask with a bottomhat
            ElemEstrukt=strel('disk',5);BH=imerode(BH,ElemEstrukt);%noise removal
            ElemEstrukt=strel('disk',10);BH=imdilate(BH,ElemEstrukt);%se dilata la mascara para quitar mejor las venas
            NV_0 = inpaintCoherent(Icropped,BH,'radius',30);%Con la mascara y la imagen original se quitan los vasos sanguineos
            %Realizamos una vez mas para mejorar la eliminacion de los vasos
            Ig=NV_0(:,:,2);
            ElemEstrukt=strel('disk',20);BH=imbothat(Ig,ElemEstrukt);BH=BH>15;
            ElemEstrukt=strel('disk',5);BH=imerode(BH,ElemEstrukt);
            ElemEstrukt=strel('disk',10);BH=imdilate(BH,ElemEstrukt);
            NV = inpaintCoherent(NV_0,BH,'radius',30);
            app.VS_Image.ImageSource=NV;

            %SEGMENTACION
            %Disco (SegmDisco)
            I_NV_gray=rgb2gray(NV);
            I_NV_gray_adj=imadjust(I_NV_gray);
            if any(I_NV_gray(:)==0) %imagenes con pixeles negros se segmentan mal, segmentar de otra manera
                Treeshold = multithresh(I_NV_gray_adj,3);
                Segm=I_NV_gray_adj>Treeshold(3);
            else
                Treeshold = multithresh(I_NV_gray_adj,4);
                Segm=I_NV_gray_adj>Treeshold(3);
            end
            ElemEstrukt=strel('disk',20);Segm=imopen(Segm,ElemEstrukt);
            Segm=bwareafilt(Segm,1);
            Segm=imfill(Segm,'holes');
            ElemEstrukt=strel('disk',250);
            SegmDisco=imclose(Segm,ElemEstrukt);


            SegmD=imfuse(SegmDisco,SegmDisco,"blend");%NO SE QUE OTRA MANERA
            app.SegmentacionDisco_Image.ImageSource=cat(3,SegmD,SegmD,SegmD);


            %Copa (SegmCopa)
            I_NV_gray=rgb2gray(NV);
            I_NV_gray_adj=imadjust(I_NV_gray);
            if any(I_NV_gray(:)==0) %imagenes con pixeles negros se segmentan mal, segmentar de otra manera
                Treeshold = multithresh(I_NV_gray_adj,5);
                Segm=I_NV_gray_adj>Treeshold(5);
            else
                Treeshold = multithresh(I_NV_gray_adj,4);
                Segm=I_NV_gray_adj>Treeshold(4);
            end

                ElemEstrukt=strel('disk',20);Segm=imopen(Segm,ElemEstrukt);
                Segm=bwareafilt(Segm,1);
                Segm=imfill(Segm,'holes');
                ElemEstrukt=strel('disk',250);
                SegmCopa=imclose(Segm,ElemEstrukt); 

                SegmC=imfuse(SegmCopa,SegmCopa,"blend");%NO SE QUE OTRA MANERA
                app.SegmentacionCopa_Image.ImageSource=cat(3,SegmC,SegmC,SegmC);
                
                %CopaYDisco(CopaDiscoSegm)
                CopaDiscoSegm=imfuse(SegmCopa,SegmDisco,"blend"); %blend: superponer la copa y el disco con composicion alfa 
                app.Segmentacion_Image.ImageSource = cat(3,CopaDiscoSegm,CopaDiscoSegm,CopaDiscoSegm); %porque solo lee imagenes rgb                

                [counts, binLocations] = imhist(I, 256); % 256 bins
                bar(app.Histograma_Axes, binLocations, counts);
                xlim(app.Histograma_Axes, [0, 255]); 


                %EXTRACCION FEATURES MODELO

                %COLOR MOMENTS
                Ic_r=Icropped(:,:,1);Ic_g=Icropped(:,:,2);Ic_b=Icropped(:,:,3);
                %Media (mean)
                Media_Red=mean(Ic_r(:));Media_Green=mean(Ic_g(:));
                Media_Blue=mean(Ic_b(:));app.MediaAzulResult_Label.Text= num2str(Media_Blue);
                %Desviación estándar (standard deviation)
                Desviac_Red=std2(Ic_r);app.DesciacionRojoResult_Label.Text= num2str(Desviac_Red);
                Desviac_Green=std2(Ic_g);app.DesviacionVerdeResult_Label.Text= num2str(Desviac_Green);
                Desviac_Blue=std2(Ic_b);

                %GRAY LEVEL COVARIANCE MATRIX
                I_read_red=Icropped(:,:,1);I_read_green=Icropped(:,:,2);I_read_blue=Icropped(:,:,3);
                I_glcm_red=graycomatrix(I_read_red);I_glcm_green=graycomatrix(I_read_green);I_glcm_blue=graycomatrix(I_read_blue);
                GrayProps_red=graycoprops(I_glcm_red);GrayProps_green=graycoprops(I_glcm_green);GrayProps_blue=graycoprops(I_glcm_blue);
                %green
                Contraste_glcm_green=GrayProps_green.Contrast;  app.ContrasteResult_Label.Text= num2str(Contraste_glcm_green);
                Correlacion_glcm_green=GrayProps_green.Correlation;  app.CorrelacionResult_Label.Text= num2str(Correlacion_glcm_green);
                Energia_glcm_green=GrayProps_green.Energy;  app.EnergiaResult_Label.Text= num2str(Energia_glcm_green);
                Homogeneidad_glcm_green=GrayProps_green.Homogeneity;  app.HomogeneidadResult_Label.Text= num2str(Homogeneidad_glcm_green);
                %blue
                Contraste_glcm_blue(i,1)=GrayProps_blue.Contrast;
                Correlacion_glcm_blue(i,1)=GrayProps_blue.Correlation;
                Energia_glcm_blue(i,1)=GrayProps_blue.Energy;
                Homogeneidad_glcm_blue(i,1)=GrayProps_blue.Homogeneity;
                

                %WAVELET
                [~,cH,cV,cD]=dwt2(Icropped,'db4')
                %Horizontal
                Contraste_wavelet_cH=0;Energia_wavelet_cH=0; Desemejanza_wavelet_cH=0;Homogeneidad_wavelet_cH=0;
                for i = 1:size(cH, 1)
                    for j = 1:size(cH, 2)
                        Contraste_wavelet_cH=((abs(i-j))^2)*cH(i,j)+Contraste_wavelet_cH;
                        Energia_wavelet_cH=(cH(i,j))^2+Energia_wavelet_cH;
                        Desemejanza_wavelet_cH=abs(i-j)*cH(i,j)+Desemejanza_wavelet_cH;
                        Homogeneidad_wavelet_cH=Homogeneidad_wavelet_cH+cH(i,j)/(1+(i-j)^2);
                    end
                end
                app.HomoCHResult_Label.Text= num2str(Homogeneidad_wavelet_cH);
                app.ContrasteCHResult_Label.Text= num2str(Contraste_wavelet_cH);
                Entropia_wavelet_cH=entropy(cH);Media_wavelet_cH=mean(cH(:));

                 %Vertical 
                Contraste_wavelet_cV=0;Energia_wavelet_cV=0; Desemejanza_wavelet_cV=0;Homogeneidad_wavelet_cV=0;
                for i = 1:size(cV, 1)
                    for j = 1:size(cV, 2)
                        Contraste_wavelet_cV=((abs(i-j))^2)*cV(i,j)+Contraste_wavelet_cV;
                        Energia_wavelet_cV=(cV(i,j))^2+Energia_wavelet_cV;
                        Desemejanza_wavelet_cV=abs(i-j)*cV(i,j)+Desemejanza_wavelet_cV;
                        Homogeneidad_wavelet_cV=Homogeneidad_wavelet_cV+cV(i,j)/(1+(i-j)^2);
                    end
                end
                app.HomoCVResult_Label.Text= num2str(Homogeneidad_wavelet_cV);
                Entropia_wavelet_cV=entropy(cV);Media_wavelet_cV=mean(cV(:));app.EntropiaCVResult_Label.Text= num2str(Entropia_wavelet_cV);

                %Diagonal 
                Contraste_wavelet_cD=0;Energia_wavelet_cD=0; Desemejanza_wavelet_cD=0;Homogeneidad_wavelet_cD=0;
                for i = 1:size(cD, 1)
                    for j = 1:size(cD, 2)
                        Contraste_wavelet_cD=((abs(i-j))^2)*cD(i,j)+Contraste_wavelet_cD;
                        Energia_wavelet_cD=(cD(i,j))^2+Energia_wavelet_cD;
                        Desemejanza_wavelet_cD=abs(i-j)*cD(i,j)+Desemejanza_wavelet_cD;
                        Homogeneidad_wavelet_cD=Homogeneidad_wavelet_cD+cD(i,j)/(1+(i-j)^2);
                    end
                end
                app.HomoCDResult_Label.Text= num2str(Homogeneidad_wavelet_cD);
                app.DesemejanzaCGResult_Label.Text= num2str(Desemejanza_wavelet_cD);
                Entropia_wavelet_cD=entropy(cD);Media_wavelet_cD=mean(cD(:));


                %Segmentacion
                AreaCopa=sum(SegmCopa(:));app.AreaCopa_Label.Text= num2str(AreaCopa);
                AreaDisco=sum(SegmDisco(:));app.AreaDiscoResult_Label.Text= num2str(AreaDisco);
                Ratio_DiscCup=AreaCopa/AreaDisco;app.RelacionAreaDiscoResul_Label.Text= num2str(Ratio_DiscCup);


                %MODELO MACHINE LEARNING (DIAGNOSTICO)
                Table2=table(Media_Blue,Desviac_Red,Desviac_Green,Contraste_wavelet_cH,Homogeneidad_wavelet_cH,Homogeneidad_wavelet_cV,Entropia_wavelet_cV,Desemejanza_wavelet_cD,Homogeneidad_wavelet_cD,Ratio_DiscCup);
                load('BinaryGLMLogisticRegressionModel.mat');
                [ypred2,~] = BinaryGLMLogisticRegressionModel.predictFcn(Table2);
                if (ypred2==0)
                    app.Diagnostico_Label.Text='Paciente SANO';
                    app.Diagnostico='0';
                end
                if(ypred2==1)
                    app.Diagnostico_Label.Text='Paciente con GLAUCOMA';
                    app.Diagnostico='1';
                end
                msgbox(['Esta aplicación sirve como apoyo al diagnóstico de glaucoma y no debe sustituir su juicio clínico profesional. Los resultados proporcionados son preliminares y deben ser confirmados mediante un examen oftalmológico completo. Por favor, utilice esta herramienta como una guía adicional y tome en cuenta su experiencia y conocimientos médicos para tomar decisiones finales sobre el diagnóstico y tratamiento del paciente.'],"Recuerda","warn");
    else
                app.Segmentacion_Label.Visible='off';
                app.Segmentacion_Image.Visible='off';
                app.Mas_Button.Visible='off';
    
                app.Entropia_Label.Visible='off';
                app.Desviacion_estandar_Label.Visible='off';
                app.Rango_dinamico_Label.Visible='off';
                app.Varianza_bordes_Label.Visible='off';
                app.Valor_maximo_bordes_Label.Visible='off';
                app.EntropiaResult_Label.Visible='off';
                app.DesviacionEstandarResult_Label.Visible='off';
                app.RangoDinamicoResult_Label.Visible='off';
                app.VarianzaBordesResult_Label.Visible='off';
                app.ValorMaximoResult_Label.Visible='off';
                app.VerMas_Button.Visible='off';
                app.Recuadro_Image_5.Visible='off';

                app.Notas_Button.Visible='off';
                app.PropiedadesdeImagen_Label.Visible='off';
                app.Recuadro_Image.Visible='off';
                app.Marco_6.Visible='off';
                app.Mas_Button.Visible='off';
                
    end


        end

        % Callback function
        function ImportanciaVariables_ButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.BaseDatos_Tab;
        end

        % Button pushed function: Guardar_Button
        function Guardar_ButtonPushed2(app, event)
            %CREACION EXCEL CON DATOS DE LOS PACIENTES
            %Dar valor a variables
            if app.Quality=='Correcta'
                Name=app.Nombre_EditField.Value;Surname=app.Apellidos_EditField.Value;
                TEntropia=app.EntropiaResult_Label.Text;TDesv=app.DesviacionEstandarResult_Label.Text;TRango=app.RangoDinamicoResult_Label.Text;
                TVarianza=app.VarianzaBordesResult_Label.Text;TValorMax=app.ValorMaximoResult_Label.Text;
                dia= datetime('now', 'Format', 'dd/MM/yyyy');dia_str=char(dia);
                ID=app.ID_EditField.Value; ArchivoNomb=app.archivo;Quality=app.Quality;
                centro=app.Centros_EditField.Value;
                Notas=app.Notas_TextArea.Value;

                Diagnostico=app.Diagnostico;
                AreaCopa=app.AreaCopa_Label.Text;AreaDisco=app.AreaDiscoResult_Label.Text;Relacion=app.RelacionAreaDiscoResul_Label.Text;
                MediaAzul=app.MediaAzulResult_Label.Text;DesviacionRojo=app.DesciacionRojoResult_Label.Text;DesviacionVerde=app.DesviacionVerdeResult_Label.Text;
                HomoCH=app.HomoCHResult_Label.Text;HomoCV=app.HomoCVResult_Label.Text;HomoCD=app.HomoCDResult_Label.Text;
                ContrasteCH=app.ContrasteCHResult_Label.Text;EntropiaCV=app.EntropiaCVResult_Label.Text;DesemejanzaCD=app.DesemejanzaCGResult_Label.Text;
                ContrasteGLCM=app.ContrasteResult_Label.Text; CorrelacionGLCM=app.CorrelacionResult_Label.Text; EnergiaGLCM=app.EnergiaResult_Label.Text; 
                HomogeneidadGLCM=app.HomogeneidadResult_Label.Text;


                CellPatient={ID,Name,Surname,centro,dia_str,Quality,Diagnostico,TEntropia,TDesv,TRango,TVarianza,TValorMax,AreaDisco,AreaCopa,Relacion,MediaAzul,DesviacionRojo,DesviacionVerde,HomoCH,HomoCV,HomoCD,ContrasteCH,...
                    EntropiaCV,DesemejanzaCD,ContrasteGLCM,CorrelacionGLCM,EnergiaGLCM,HomogeneidadGLCM,ArchivoNomb,Notas};
                Tpatient=cell2table(CellPatient,'VariableNames', {'ID paciente','Nombre del sanitario', 'Apellidos del sanitario','Centro','Fecha', 'Calidad de la imágen','Diagnostico', 'Entropía', 'Desviación estándar',...
                    'Rango dinámico','Varianza bordes','Valor máximo bordes','Area Disco óptico','Area Copa óptica','Relación Area Disco','Intesidad media canal azul','Desviacion estándar canal rojo','Desviacion estándar canal verde',...
                    'Homogeneidad wavelet horizontal','Homogeneidad wavelet vertical','Homogeneidad wavelet diagonal','Contraste wavelet horizontal','Entropia wavelet vertical','Desemejanza wavelet diagonal',...
                    'Contraste matriz de coocurrencia','Correlación matriz de coocurrencia','Energia matriz de coocurrencia','Homogeneidad matriz de coocurrencia','Archivo','Notas'});%Añadir diagnostico y parametros de la segmentacion
    
                %Actualizar datos de el excel
                PathPC=getenv('USERPROFILE');%C:\Users\ikera
                PathDesktop=fullfile(PathPC,'Desktop');%C:\Users\ikera\Desktop
                Full_path=fullfile(PathDesktop,'Patients_data.xlsx');
                writetable(Tpatient,Full_path,'WriteMode','append');%Append para actualizar la tabla
            end
              
            %Nuevo paciente (volver pagina principal)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
            %Imagen, labels y botones del tab2 de 0
            %app.Nombre_EditField.Value='';
            %app.Apellidos_EditField.Value='';
            app.ID_EditField.Value='';
            %app.Centros_EditField.Value='';
            app.Diagnostico_Label.Text='';
            app.Contra_EditField.Value='';

            app.EyeFundus_Image.Visible='off';
            app.Inserteimagen_Button.Visible='on';
            app.Recuadro_Image.Visible='off';
            %app.Guardar_Button.Visible='off';
            app.Nombreyapellido_Label.Visible='off';
            app.PropiedadesdeImagen_Label.Visible='off';
            app.Segmentacion_Label.Visible='off';
            app.ErrorInsertar_Label.Visible='off';
            app.Diagnostico_Label.Visible='off';
            app.Segmentacion_Label.Visible='off';
            app.Calidad_Label.Visible='off';
            app.Segmentacion_Label.Visible='off';
            app.Diagnostico_Label.Visible='off';
            app.Segmentacion_Image.Visible='off';
            app.Notas_Button.Visible='off';
            app.Notas_TextArea.Value='';
            app.Guardar_Button.Visible='off';

            app.Entropia_Label.Visible='off';
            app.Desviacion_estandar_Label.Visible='off';
            app.Rango_dinamico_Label.Visible='off';
            app.Varianza_bordes_Label.Visible='off';
            app.Valor_maximo_bordes_Label.Visible='off';

            app.EntropiaResult_Label.Visible='off';
            app.DesviacionEstandarResult_Label.Visible='off';
            app.RangoDinamicoResult_Label.Visible='off';
            app.VarianzaBordesResult_Label.Visible='off';
            app.ValorMaximoResult_Label.Visible='off';
            app.Histograma_Axes.Visible='off';
            app.VerMas_Button.Visible='off';
            app.Recuadro_Image_5.Visible='off';
            app.Marco_6.Visible='off';
            app.Mas_Button.Visible='off';

            
        end

        % Button pushed function: Equipo_Button
        function Equipo_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Equipo_Tab;
        end

        % Callback function
        function Atras4_Button_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.Sobre_nosotros_Tab;
        end

        % Button pushed function: Inicio4_Button_3
        function Inicio4_Button_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Callback function
        function Contactanos_ButtonPushed(app, event)
            app.TabGroup.SelectedTab= app.Contactanos_Tab;
        end

        % Callback function
        function Atras4_Button_6Pushed(app, event)
            app.TabGroup.SelectedTab= app.Sobre_nosotros_Tab;
        end

        % Callback function
        function Inicio4_Button_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: Mas_Button
        function Mas_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Mas_Tab;
        end

        % Callback function
        function Atras3_Button_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button pushed function: Notas_Button
        function Notas_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Notas_Tab;
        end

        % Callback function
        function Atras3_Button_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button pushed function: Encuentranos_Button
        function Encuentranos_ButtonPushed(app, event)
            URL = 'https://maps.app.goo.gl/2f4thFkHBZJJfMG76';  
            web(URL, '-browser');
        end

        % Callback function
        function Atras3_Button_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button pushed function: VerMas_Button
        function VerMas_ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Propiedades_Tab;
        end

        % Value changing function: Contra_EditField
        function Contra_EditFieldValueChanging(app, event)
         
        end

        % Value changed function: Contra_EditField
        function Contra_EditFieldValueChanged(app, event)

        end

        % Callback function
        function Inserteimagen_Button_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Callback function
        function Image8Clicked(app, event)
             app.TabGroup.SelectedTab = app.Tab2;
        end

        % Callback function
        function Image8_3Clicked(app, event)
             app.TabGroup.SelectedTab = app.Tab2;
        end

        % Callback function
        function Image8_4Clicked(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Callback function
        function Image8_7Clicked(app, event)
            app.TabGroup.SelectedTab = app.Sobre_nosotros_Tab;
        end

        % Callback function
        function Image8_6Clicked(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Callback function
        function Image8_5Clicked(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Button pushed function: SobreNosotros_Button_2
        function SobreNosotros_Button_2Pushed(app, event)
            URL = 'https://ikeramor.github.io/pbl_grupo_3/';  
            web(URL, '-browser');
        end

        % Image clicked function: Image8_17
        function Image8_17Clicked(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Image clicked function: Image8_16
        function Image8_16Clicked(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Image clicked function: Image8_15
        function Image8_15Clicked(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Image clicked function: Image8_14
        function Image8_14Clicked(app, event)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
        end

        % Image clicked function: Image8_13
        function Image8_13Clicked(app, event)
            app.TabGroup.SelectedTab = app.AcercaDe_Tab;
        end

        % Image clicked function: Image8_12
        function Image8_12Clicked(app, event)
            app.TabGroup.SelectedTab = app.Sobre_nosotros_Tab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ventana1 = uifigure('Visible', 'off');
            app.ventana1.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.ventana1);
            app.VentanaInicio_Tab = uitab(app.TabGroup);
            app.VentanaInicio_Tab.Title = 'Ventana_inicio';
            app.Marco2_Image_2 = uiimage(app.VentanaInicio_Tab);
            app.Marco2_Image_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.MU_Image = uiimage(app.VentanaInicio_Tab);
            app.MU_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard_Image = uiimage(app.VentanaInicio_Tab);
            app.VisioGuard_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.Continuar_Button = uibutton(app.VentanaInicio_Tab, 'push');
            app.Continuar_Button.ButtonPushedFcn = createCallbackFcn(app, @Continuar_ButtonPushed, true);
            app.Continuar_Button.Text = 'Continuar';
            app.Apellidos_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.Nombre_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.ErrorInicio_Label = uilabel(app.VentanaInicio_Tab);
            app.ErrorInicio_Label.Visible = 'off';
            app.ErrorInicio_Label.Text = 'Asegurese de rellenar todos los campos';
            app.Apellidos_Label = uilabel(app.VentanaInicio_Tab);
            app.Apellidos_Label.Text = 'Apellidos';
            app.Nombre_Label = uilabel(app.VentanaInicio_Tab);
            app.Nombre_Label.Text = 'Nombre';
            app.Usuario_Image = uiimage(app.VentanaInicio_Tab);
            app.Usuario_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'user.png');
            app.GlaucoScan_Label = uilabel(app.VentanaInicio_Tab);
            app.GlaucoScan_Label.Text = 'GlaucoScan';
            app.CE_Image = uiimage(app.VentanaInicio_Tab);
            app.CE_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.AcercaDe_Button = uibutton(app.VentanaInicio_Tab, 'push');
            app.AcercaDe_Button.ButtonPushedFcn = createCallbackFcn(app, @AcercaDe_ButtonPushed, true);
            app.AcercaDe_Button.Text = 'Acerca de';
            app.Fecha_Label = uilabel(app.VentanaInicio_Tab);
            app.Fecha_Label.Text = 'Fecha';
            app.ID_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.ID_Label = uilabel(app.VentanaInicio_Tab);
            app.ID_Label.Text = 'ID paciente';
            app.Centros_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.Centros_Label = uilabel(app.VentanaInicio_Tab);
            app.Centros_Label.Text = 'Centro ';
            app.Error_Label_4 = uilabel(app.VentanaInicio_Tab);
            app.Error_Label_4.Visible = 'off';
            app.Error_Label_4.Text = '⚠️';
            app.Error_Label_3 = uilabel(app.VentanaInicio_Tab);
            app.Error_Label_3.Visible = 'off';
            app.Error_Label_3.Text = '⚠️';
            app.Error_Label_2 = uilabel(app.VentanaInicio_Tab);
            app.Error_Label_2.Visible = 'off';
            app.Error_Label_2.Text = '⚠️';
            app.Error_Label_1 = uilabel(app.VentanaInicio_Tab);
            app.Error_Label_1.Visible = 'off';
            app.Error_Label_1.Text = '⚠️';
            app.Contra_Label = uilabel(app.VentanaInicio_Tab);
            app.Contra_Label.Text = 'Contraseña';
            app.Error_Label_5 = uilabel(app.VentanaInicio_Tab);
            app.Error_Label_5.Visible = 'off';
            app.Error_Label_5.Text = '⚠️';
            app.Contra_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.Contra_EditField.ValueChangedFcn = createCallbackFcn(app, @Contra_EditFieldValueChanged, true);
            app.Contra_EditField.ValueChangingFcn = createCallbackFcn(app, @Contra_EditFieldValueChanging, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Marco2_Image_7 = uiimage(app.Tab2);
            app.Marco2_Image_7.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.Recuadro_Image_5 = uiimage(app.Tab2);
            app.Recuadro_Image_5.Visible = 'off';
            app.Recuadro_Image_5.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.Recuadro_Image = uiimage(app.Tab2);
            app.Recuadro_Image.Visible = 'off';
            app.Recuadro_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.Diagnostico_Label = uilabel(app.Tab2);
            app.Diagnostico_Label.Visible = 'off';
            app.Diagnostico_Label.Text = 'Diagnostico :';
            app.MU2_Image = uiimage(app.Tab2);
            app.MU2_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard2_Image = uiimage(app.Tab2);
            app.VisioGuard2_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.EyeFundus_Image = uiimage(app.Tab2);
            app.EyeFundus_Image.Visible = 'off';
            app.Inserteimagen_Button = uibutton(app.Tab2, 'push');
            app.Inserteimagen_Button.ButtonPushedFcn = createCallbackFcn(app, @Inserteimagen_ButtonPushed2, true);
            app.Inserteimagen_Button.Text = 'Inserte imágen';
            app.Nombreyapellido_Label = uilabel(app.Tab2);
            app.Nombreyapellido_Label.Visible = 'off';
            app.Nombreyapellido_Label.Text = 'Nombre y apellido';
            app.Guardar_Button = uibutton(app.Tab2, 'push');
            app.Guardar_Button.ButtonPushedFcn = createCallbackFcn(app, @Guardar_ButtonPushed2, true);
            app.Guardar_Button.Visible = 'off';
            app.Guardar_Button.Text = 'Guardar y salir';
            app.ErrorInsertar_Label = uilabel(app.Tab2);
            app.ErrorInsertar_Label.Visible = 'off';
            app.ErrorInsertar_Label.Text = 'Ha ocurrido un error. Intente insertar otra imágen.';
            app.PropiedadesdeImagen_Label = uilabel(app.Tab2);
            app.PropiedadesdeImagen_Label.Visible = 'off';
            app.PropiedadesdeImagen_Label.Text = 'Propiedades de Imagen';
            app.Calidad_Label = uilabel(app.Tab2);
            app.Calidad_Label.Visible = 'off';
            app.Calidad_Label.Text = 'Calidad :';
            app.Segmentacion_Label = uilabel(app.Tab2);
            app.Segmentacion_Label.Visible = 'off';
            app.Segmentacion_Label.Text = 'Segmentación';
            app.GlaucoScan2_Label = uilabel(app.Tab2);
            app.GlaucoScan2_Label.Text = 'GlaucoScan';
            app.Segmentacion_Image = uiimage(app.Tab2);
            app.Segmentacion_Image.Visible = 'off';
            app.Entropia_Label = uilabel(app.Tab2);
            app.Entropia_Label.Visible = 'off';
            app.Entropia_Label.Text = 'Entropia:';
            app.Desviacion_estandar_Label = uilabel(app.Tab2);
            app.Desviacion_estandar_Label.Visible = 'off';
            app.Desviacion_estandar_Label.Text = 'Desviacion estandar:';
            app.Rango_dinamico_Label = uilabel(app.Tab2);
            app.Rango_dinamico_Label.Visible = 'off';
            app.Rango_dinamico_Label.Text = 'Rango dinámico:';
            app.Varianza_bordes_Label = uilabel(app.Tab2);
            app.Varianza_bordes_Label.Visible = 'off';
            app.Varianza_bordes_Label.Text = 'Varianza bordes:';
            app.Valor_maximo_bordes_Label = uilabel(app.Tab2);
            app.Valor_maximo_bordes_Label.Visible = 'off';
            app.Valor_maximo_bordes_Label.Text = 'Valor maximo bordes:';
            app.EntropiaResult_Label = uilabel(app.Tab2);
            app.EntropiaResult_Label.Visible = 'off';
            app.EntropiaResult_Label.Text = 'Result';
            app.DesviacionEstandarResult_Label = uilabel(app.Tab2);
            app.DesviacionEstandarResult_Label.Visible = 'off';
            app.DesviacionEstandarResult_Label.Text = 'Result';
            app.RangoDinamicoResult_Label = uilabel(app.Tab2);
            app.RangoDinamicoResult_Label.Visible = 'off';
            app.RangoDinamicoResult_Label.Text = 'Result';
            app.VarianzaBordesResult_Label = uilabel(app.Tab2);
            app.VarianzaBordesResult_Label.Visible = 'off';
            app.VarianzaBordesResult_Label.Text = 'Result';
            app.ValorMaximoResult_Label = uilabel(app.Tab2);
            app.ValorMaximoResult_Label.Visible = 'off';
            app.ValorMaximoResult_Label.Text = 'Result';
            app.Espere_Label = uilabel(app.Tab2);
            app.Espere_Label.Visible = 'off';
            app.Espere_Label.Text = 'Cargando... Ten paciencia.';
            app.Mas_Button = uibutton(app.Tab2, 'push');
            app.Mas_Button.ButtonPushedFcn = createCallbackFcn(app, @Mas_ButtonPushed, true);
            app.Mas_Button.Visible = 'off';
            app.Mas_Button.Text = 'Más';
            app.Notas_Button = uibutton(app.Tab2, 'push');
            app.Notas_Button.ButtonPushedFcn = createCallbackFcn(app, @Notas_ButtonPushed, true);
            app.Notas_Button.Visible = 'off';
            app.Notas_Button.Text = 'Notas';
            app.CE4_Image_4 = uiimage(app.Tab2);
            app.CE4_Image_4.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.VerMas_Button = uibutton(app.Tab2, 'push');
            app.VerMas_Button.ButtonPushedFcn = createCallbackFcn(app, @VerMas_ButtonPushed, true);
            app.VerMas_Button.Visible = 'off';
            app.VerMas_Button.Text = 'Ver más';
            app.Marco_6 = uiimage(app.Tab2);
            app.Marco_6.Visible = 'off';
            app.Marco_6.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'MarcoROI.png');
            app.Propiedades_Tab = uitab(app.TabGroup);
            app.Propiedades_Tab.Title = 'Propiedades_de_Imágen';
            app.Histograma_Axes = uiaxes(app.Propiedades_Tab);
            title(app.Histograma_Axes, 'Histograma')
            app.Histograma_Axes.XTick = [0 51 102 153 204 255];
            app.Histograma_Axes.XTickLabel = {'0'; '51'; '102'; '153'; '204'; '255'};
            app.Histograma_Axes.ZTick = [];
            app.Recuadro_Image_6 = uiimage(app.Propiedades_Tab);
            app.Recuadro_Image_6.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.Marco3_Image_3 = uiimage(app.Propiedades_Tab);
            app.Marco3_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.MU3_Image_3 = uiimage(app.Propiedades_Tab);
            app.MU3_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard3_Image2_2 = uiimage(app.Propiedades_Tab);
            app.VisioGuard3_Image2_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan3_Label_2 = uilabel(app.Propiedades_Tab);
            app.GlaucoScan3_Label_2.Text = 'GlaucoScan';
            app.CE3_Image_2 = uiimage(app.Propiedades_Tab);
            app.CE3_Image_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Entropia_Label_2 = uilabel(app.Propiedades_Tab);
            app.Entropia_Label_2.Text = 'Entropia:';
            app.Desviacion_estandar_Label_2 = uilabel(app.Propiedades_Tab);
            app.Desviacion_estandar_Label_2.Text = 'Desviacion estandar:';
            app.Rango_dinamico_Label_2 = uilabel(app.Propiedades_Tab);
            app.Rango_dinamico_Label_2.Text = 'Rango dinámico:';
            app.Varianza_bordes_Label_2 = uilabel(app.Propiedades_Tab);
            app.Varianza_bordes_Label_2.Text = 'Varianza bordes:';
            app.Valor_maximo_bordes_Label_2 = uilabel(app.Propiedades_Tab);
            app.Valor_maximo_bordes_Label_2.Text = 'Valor maximo bordes:';
            app.EntropiaResult_Label_2 = uilabel(app.Propiedades_Tab);
            app.EntropiaResult_Label_2.Text = 'Result';
            app.DesviacionEstandarResult_Label_2 = uilabel(app.Propiedades_Tab);
            app.DesviacionEstandarResult_Label_2.Text = 'Result';
            app.RangoDinamicoResult_Label_2 = uilabel(app.Propiedades_Tab);
            app.RangoDinamicoResult_Label_2.Text = 'Result';
            app.VarianzaBordesResult_Label_2 = uilabel(app.Propiedades_Tab);
            app.VarianzaBordesResult_Label_2.Text = 'Result';
            app.ValorMaximoResult_Label_2 = uilabel(app.Propiedades_Tab);
            app.ValorMaximoResult_Label_2.Text = 'Result';
            app.Etiqueta_Label_2 = uilabel(app.Propiedades_Tab);
            app.Etiqueta_Label_2.Text = 'Cribado de calidad';
            app.Etiqueta_Label_3 = uilabel(app.Propiedades_Tab);
            app.Etiqueta_Label_3.Text = 'Matriz de coocurrencia (canal verde)';
            app.Etiqueta_Label_4 = uilabel(app.Propiedades_Tab);
            app.Etiqueta_Label_4.Text = 'Wavelet';
            app.Recuadro_Image_3 = uiimage(app.Propiedades_Tab);
            app.Recuadro_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.Etiqueta_Label_5 = uilabel(app.Propiedades_Tab);
            app.Etiqueta_Label_5.Text = 'Color moments';
            app.Recuadro_Image_4 = uiimage(app.Propiedades_Tab);
            app.Recuadro_Image_4.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.Etiqueta_Label_6 = uilabel(app.Propiedades_Tab);
            app.Etiqueta_Label_6.Text = 'Segmentación';
            app.Recuadro_Image_7 = uiimage(app.Propiedades_Tab);
            app.Recuadro_Image_7.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.ADisco_Labe = uilabel(app.Propiedades_Tab);
            app.ADisco_Labe.Text = 'Area disco:';
            app.ACopa_Label = uilabel(app.Propiedades_Tab);
            app.ACopa_Label.Text = 'Area copa:';
            app.RelacionAD_Label = uilabel(app.Propiedades_Tab);
            app.RelacionAD_Label.Text = 'Relación area disco:';
            app.AreaDiscoResult_Label = uilabel(app.Propiedades_Tab);
            app.AreaDiscoResult_Label.Text = 'Result';
            app.AreaCopa_Label = uilabel(app.Propiedades_Tab);
            app.AreaCopa_Label.Text = 'Result';
            app.RelacionAreaDiscoResul_Label = uilabel(app.Propiedades_Tab);
            app.RelacionAreaDiscoResul_Label.Text = 'Result';
            app.Image8_17 = uiimage(app.Propiedades_Tab);
            app.Image8_17.ImageClickedFcn = createCallbackFcn(app, @Image8_17Clicked, true);
            app.Image8_17.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'flecha2.png');
            app.Recuadro_Image_8 = uiimage(app.Propiedades_Tab);
            app.Recuadro_Image_8.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Recuadro3.png');
            app.MediaAzul_Label = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label.Text = 'Media canal azul:';
            app.DesviacionRojo_Label = uilabel(app.Propiedades_Tab);
            app.DesviacionRojo_Label.Text = 'Desviacion canal rojo:';
            app.DesviacionVerde_Label = uilabel(app.Propiedades_Tab);
            app.DesviacionVerde_Label.Text = 'Desviacion canal verde:';
            app.MediaAzulResult_Label = uilabel(app.Propiedades_Tab);
            app.MediaAzulResult_Label.Text = 'Result';
            app.DesciacionRojoResult_Label = uilabel(app.Propiedades_Tab);
            app.DesciacionRojoResult_Label.Text = 'Result';
            app.DesviacionVerdeResult_Label = uilabel(app.Propiedades_Tab);
            app.DesviacionVerdeResult_Label.Text = 'Result';
            app.MediaAzul_Label_2 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_2.Text = 'Contraste horiz:';
            app.MediaAzul_Label_3 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_3.Text = 'Homogeneidad horiz:';
            app.MediaAzul_Label_4 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_4.Text = 'Homogeneidad vert:';
            app.MediaAzul_Label_5 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_5.Text = 'Entropia vert:';
            app.MediaAzul_Label_6 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_6.Text = 'Desemejanza diag:';
            app.MediaAzul_Label_7 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_7.Text = 'Homogeneidad diag:';
            app.HomoCHResult_Label = uilabel(app.Propiedades_Tab);
            app.HomoCHResult_Label.Text = 'Result';
            app.HomoCVResult_Label = uilabel(app.Propiedades_Tab);
            app.HomoCVResult_Label.Text = 'Result';
            app.HomoCDResult_Label = uilabel(app.Propiedades_Tab);
            app.HomoCDResult_Label.Text = 'Result';
            app.ContrasteCHResult_Label = uilabel(app.Propiedades_Tab);
            app.ContrasteCHResult_Label.Text = 'Result';
            app.EntropiaCVResult_Label = uilabel(app.Propiedades_Tab);
            app.EntropiaCVResult_Label.Text = 'Result';
            app.DesemejanzaCGResult_Label = uilabel(app.Propiedades_Tab);
            app.DesemejanzaCGResult_Label.Text = 'Result';
            app.MediaAzul_Label_8 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_8.Text = 'Contraste:';
            app.MediaAzul_Label_9 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_9.Text = 'Correlación:';
            app.MediaAzul_Label_10 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_10.Text = 'Energia:';
            app.MediaAzul_Label_11 = uilabel(app.Propiedades_Tab);
            app.MediaAzul_Label_11.Text = 'Homogeneidad:';
            app.ContrasteResult_Label = uilabel(app.Propiedades_Tab);
            app.ContrasteResult_Label.Text = 'Result';
            app.CorrelacionResult_Label = uilabel(app.Propiedades_Tab);
            app.CorrelacionResult_Label.Text = 'Result';
            app.EnergiaResult_Label = uilabel(app.Propiedades_Tab);
            app.EnergiaResult_Label.Text = 'Result';
            app.HomogeneidadResult_Label = uilabel(app.Propiedades_Tab);
            app.HomogeneidadResult_Label.Text = 'Result';
            app.Mas_Tab = uitab(app.TabGroup);
            app.Mas_Tab.Title = 'Mas';
            app.Marco2_Image_5 = uiimage(app.Mas_Tab);
            app.Marco2_Image_5.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.ImagenOriginal_Image = uiimage(app.Mas_Tab);
            app.MU2_Image_2 = uiimage(app.Mas_Tab);
            app.MU2_Image_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard2_Image_2 = uiimage(app.Mas_Tab);
            app.VisioGuard2_Image_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.VS_Label = uilabel(app.Mas_Tab);
            app.VS_Label.Text = 'Eliminacion de vasos sanguíneos';
            app.GlaucoScan2_Label_2 = uilabel(app.Mas_Tab);
            app.GlaucoScan2_Label_2.Text = 'GlaucoScan';
            app.ImagenOriginal_Label = uilabel(app.Mas_Tab);
            app.ImagenOriginal_Label.Text = 'Imagen original';
            app.ExtraccionROI_Label = uilabel(app.Mas_Tab);
            app.ExtraccionROI_Label.Text = 'Extracción del ROI';
            app.SegmentacionDisco_Label = uilabel(app.Mas_Tab);
            app.SegmentacionDisco_Label.Text = 'Segmentación Disco';
            app.SegmentacionCopa_Label = uilabel(app.Mas_Tab);
            app.SegmentacionCopa_Label.Text = 'Segmentación Copa';
            app.ExtraccionROI_Image = uiimage(app.Mas_Tab);
            app.VS_Image = uiimage(app.Mas_Tab);
            app.SegmentacionDisco_Image = uiimage(app.Mas_Tab);
            app.SegmentacionCopa_Image = uiimage(app.Mas_Tab);
            app.Marco = uiimage(app.Mas_Tab);
            app.Marco.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'MarcoROI.png');
            app.Marco_2 = uiimage(app.Mas_Tab);
            app.Marco_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'MarcoROI.png');
            app.Marco_4 = uiimage(app.Mas_Tab);
            app.Marco_4.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'MarcoROI.png');
            app.Marco_5 = uiimage(app.Mas_Tab);
            app.Marco_5.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'MarcoROI.png');
            app.CE_Image_2 = uiimage(app.Mas_Tab);
            app.CE_Image_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Image8_16 = uiimage(app.Mas_Tab);
            app.Image8_16.ImageClickedFcn = createCallbackFcn(app, @Image8_16Clicked, true);
            app.Image8_16.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'flecha2.png');
            app.Notas_Tab = uitab(app.TabGroup);
            app.Notas_Tab.Title = 'Notas';
            app.Marco2_Image_6 = uiimage(app.Notas_Tab);
            app.Marco2_Image_6.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.MU2_Image_3 = uiimage(app.Notas_Tab);
            app.MU2_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard2_Image_3 = uiimage(app.Notas_Tab);
            app.VisioGuard2_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan2_Label_3 = uilabel(app.Notas_Tab);
            app.GlaucoScan2_Label_3.Text = 'GlaucoScan';
            app.ExtraccionROI_Label_2 = uilabel(app.Notas_Tab);
            app.ExtraccionROI_Label_2.Text = 'Notas';
            app.Notas_TextArea = uitextarea(app.Notas_Tab);
            app.CE_Image_3 = uiimage(app.Notas_Tab);
            app.CE_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Image8_15 = uiimage(app.Notas_Tab);
            app.Image8_15.ImageClickedFcn = createCallbackFcn(app, @Image8_15Clicked, true);
            app.Image8_15.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'flecha2.png');
            app.AcercaDe_Tab = uitab(app.TabGroup);
            app.AcercaDe_Tab.Title = 'AcercaDe_Tab';
            app.Marco2_Image_8 = uiimage(app.AcercaDe_Tab);
            app.Marco2_Image_8.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.MU3_Image = uiimage(app.AcercaDe_Tab);
            app.MU3_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard3_Image = uiimage(app.AcercaDe_Tab);
            app.VisioGuard3_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan_Label_2 = uilabel(app.AcercaDe_Tab);
            app.GlaucoScan_Label_2.Text = 'GlaucoScan';
            app.CE2_Image = uiimage(app.AcercaDe_Tab);
            app.CE2_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.SobreNosotros_Button = uibutton(app.AcercaDe_Tab, 'push');
            app.SobreNosotros_Button.ButtonPushedFcn = createCallbackFcn(app, @SobreNosotros_ButtonButtonPushed, true);
            app.SobreNosotros_Button.Text = 'Sobre nosotros';
            app.Etiqueta_Button = uibutton(app.AcercaDe_Tab, 'push');
            app.Etiqueta_Button.ButtonPushedFcn = createCallbackFcn(app, @Etiqueta_ButtonButtonPushed, true);
            app.Etiqueta_Button.Text = 'Etiqueta';
            app.Instruccione_Button = uibutton(app.AcercaDe_Tab, 'push');
            app.Instruccione_Button.ButtonPushedFcn = createCallbackFcn(app, @Instruccione_ButtonButtonPushed, true);
            app.Instruccione_Button.Text = 'Instrucciones de uso';
            app.AcercaDe2_Label = uilabel(app.AcercaDe_Tab);
            app.AcercaDe2_Label.Text = 'Acerca de';
            app.Instrucciones_Image = uiimage(app.AcercaDe_Tab);
            app.Instrucciones_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'instrucciones_uso.png');
            app.SobreNosotros_Image = uiimage(app.AcercaDe_Tab);
            app.SobreNosotros_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'SobreNosotros_logo.png');
            app.Etiqueta_Image = uiimage(app.AcercaDe_Tab);
            app.Etiqueta_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'etiqueta_logo.png');
            app.SobreNosotros_Button_2 = uibutton(app.AcercaDe_Tab, 'push');
            app.SobreNosotros_Button_2.ButtonPushedFcn = createCallbackFcn(app, @SobreNosotros_Button_2Pushed, true);
            app.SobreNosotros_Button_2.Text = 'Página Web';
            app.SobreNosotros_Image_2 = uiimage(app.AcercaDe_Tab);
            app.SobreNosotros_Image_2.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'web.png');
            app.Image8_14 = uiimage(app.AcercaDe_Tab);
            app.Image8_14.ImageClickedFcn = createCallbackFcn(app, @Image8_14Clicked, true);
            app.Image8_14.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'flecha2.png');
            app.Sobre_nosotros_Tab = uitab(app.TabGroup);
            app.Sobre_nosotros_Tab.Title = 'Sobre_nosotros';
            app.Marco2_Image_9 = uiimage(app.Sobre_nosotros_Tab);
            app.Marco2_Image_9.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.MU4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.MU4_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.VisioGuard4_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan4_Label = uilabel(app.Sobre_nosotros_Tab);
            app.GlaucoScan4_Label.Text = 'GlaucoScan';
            app.Inicio4_Button = uibutton(app.Sobre_nosotros_Tab, 'push');
            app.Inicio4_Button.ButtonPushedFcn = createCallbackFcn(app, @Inicio4_ButtonPushed, true);
            app.Inicio4_Button.Text = 'Inicio';
            app.CE4_Image = uiimage(app.Sobre_nosotros_Tab);
            app.CE4_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Sobre_nosotros_Label = uilabel(app.Sobre_nosotros_Tab);
            app.Sobre_nosotros_Label.Text = 'Sobre nosotros';
            app.Texto_sobre_nosotros_TextArea = uitextarea(app.Sobre_nosotros_Tab);
            app.Texto_sobre_nosotros_TextArea.Editable = 'off';
            app.Texto_sobre_nosotros_TextArea.Value = {'¡Bienvenido a VisioGuard!'; ''; 'VisioGuard es una empresa emergente con sede en Arrasate (Gipuzkoa, Euskal Herria), fundada en el año 2024 y compuesta por un equipo apasionado de siete individuos. De esos siete, cinco son fundadores y provienen de un trasfondo en ingeniería biomédica de Mondragon Unibertitatea.'; ''; 'Nos enorgullece ofrecer soluciones innovadoras en el campo de la salud, específicamente centradas en la detección temprana y precisa del glaucoma. Creemos firmemente en el poder de la tecnología para mejorar la calidad de vida de las personas, y es por eso que hemos dirigido nuestros esfuerzos hacia el desarrollo de software de vanguardia que pueda ayudar a diagnosticar esta enfermedad ocular de manera rápida y efectiva.'; ''; 'En VisioGuard, estamos comprometidos con la excelencia en cada paso del camino. Desde la concepción de nuestras ideas hasta la implementación y la entrega final, nos esforzamos por mantener los más altos estándares de calidad y precisión. Nuestro equipo multidisciplinario combina habilidades técnicas con un profundo entendimiento de las necesidades clínicas, lo que nos permite crear soluciones que realmente marcan la diferencia en la atención médica.'; ''; 'En VisioGuard, nuestro objetivo es claro: mejorar la salud visual y transformar vidas a través de la innovación tecnológica.'; ''; '¡Gracias por elegir VisioGuard!'};
            app.Slogan_Label = uilabel(app.Sobre_nosotros_Tab);
            app.Slogan_Label.Text = 'Ojos que no ven, GlaucoScan que lo siente';
            app.Equipo_Button = uibutton(app.Sobre_nosotros_Tab, 'push');
            app.Equipo_Button.ButtonPushedFcn = createCallbackFcn(app, @Equipo_ButtonPushed, true);
            app.Equipo_Button.Text = 'Equipo';
            app.Encuentranos_Button = uibutton(app.Sobre_nosotros_Tab, 'push');
            app.Encuentranos_Button.ButtonPushedFcn = createCallbackFcn(app, @Encuentranos_ButtonPushed, true);
            app.Encuentranos_Button.Text = 'Encuéntranos';
            app.Image8_13 = uiimage(app.Sobre_nosotros_Tab);
            app.Image8_13.ImageClickedFcn = createCallbackFcn(app, @Image8_13Clicked, true);
            app.Image8_13.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'flecha2.png');
            app.Equipo_Tab = uitab(app.TabGroup);
            app.Equipo_Tab.Title = 'Equipo';
            app.Marco2_Image_10 = uiimage(app.Equipo_Tab);
            app.Marco2_Image_10.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'marco.png');
            app.MU4_Image_3 = uiimage(app.Equipo_Tab);
            app.MU4_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard4_Image_3 = uiimage(app.Equipo_Tab);
            app.VisioGuard4_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Captura3-removebg-preview.png');
            app.GlaucoScan4_Label_3 = uilabel(app.Equipo_Tab);
            app.GlaucoScan4_Label_3.Text = 'GlaucoScan';
            app.CE4_Image_3 = uiimage(app.Equipo_Tab);
            app.CE4_Image_3.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ce-mark-symbol-for-icon-design-free-png.png');
            app.Inicio4_Button_3 = uibutton(app.Equipo_Tab, 'push');
            app.Inicio4_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Inicio4_Button_3Pushed, true);
            app.Inicio4_Button_3.Text = 'Inicio';
            app.Equipo_Label = uilabel(app.Equipo_Tab);
            app.Equipo_Label.Text = 'Equipo';
            app.Elisa_Image = uiimage(app.Equipo_Tab);
            app.Elisa_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Elisa.png');
            app.Asier_Image = uiimage(app.Equipo_Tab);
            app.Asier_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Asi.png');
            app.ane_Image = uiimage(app.Equipo_Tab);
            app.ane_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'ane.png');
            app.paula_Image = uiimage(app.Equipo_Tab);
            app.paula_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Paula.png');
            app.IkerAmor_Label = uilabel(app.Equipo_Tab);
            app.IkerAmor_Label.Text = 'Iker Amor Rodríguez';
            app.Ane_Label = uilabel(app.Equipo_Tab);
            app.Ane_Label.Text = 'Ane Zabala Mallona';
            app.Paula_Label = uilabel(app.Equipo_Tab);
            app.Paula_Label.Text = 'Paula Villarino Barbero';
            app.Elisa_Label = uilabel(app.Equipo_Tab);
            app.Elisa_Label.Text = 'Elisa García de Eulate Pérez';
            app.Asier_Label = uilabel(app.Equipo_Tab);
            app.Asier_Label.Text = 'Asier Morcillo Etxarri';
            app.Iker_Image = uiimage(app.Equipo_Tab);
            app.Iker_Image.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'Iker.png');
            app.IkerAmor_Label_2 = uilabel(app.Equipo_Tab);
            app.IkerAmor_Label_2.Text = 'Director de tecnologia y jefe de desarrollo';
            app.Elisa_Label_2 = uilabel(app.Equipo_Tab);
            app.Elisa_Label_2.Text = 'Responsable técnica y directora de calidad';
            app.Asi_Label_ = uilabel(app.Equipo_Tab);
            app.Asi_Label_.Text = 'Director financiero';
            app.paula_Label_2 = uilabel(app.Equipo_Tab);
            app.paula_Label_2.Text = 'Jefa de investigación y directora general';
            app.Ane_Label_2 = uilabel(app.Equipo_Tab);
            app.Ane_Label_2.Text = 'Directora de operaciones y soporte al cliente';
            app.Image8_12 = uiimage(app.Equipo_Tab);
            app.Image8_12.ImageClickedFcn = createCallbackFcn(app, @Image8_12Clicked, true);
            app.Image8_12.ImageSource = fullfile(pathToMLAPP, 'archivos_app', 'flecha2.png');
            % Show the figure after all components are created
            app.ventana1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisioGuard_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana1)
        end
    end
end